classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        lampCH5                         matlab.ui.control.Lamp
        lampCH4                         matlab.ui.control.Lamp
        lampCH3                         matlab.ui.control.Lamp
        lampCH2                         matlab.ui.control.Lamp
        lampCH1                         matlab.ui.control.Lamp
        Tree                            matlab.ui.container.CheckBoxTree
        MTrapDescendensLNode            matlab.ui.container.TreeNode
        MTrapDescendensRNode            matlab.ui.container.TreeNode
        MDeltodeusAnteriorLNode         matlab.ui.container.TreeNode
        MDeltodeusAnteriorRNode         matlab.ui.container.TreeNode
        MExtCarpiRadialisdomNode        matlab.ui.container.TreeNode
        Image_2                         matlab.ui.control.Image
        MethodDropDown                  matlab.ui.control.DropDown
        MethodDropDownLabel             matlab.ui.control.Label
        AutomaticfilterparametersLabel  matlab.ui.control.Label
        Switch_2                        matlab.ui.control.Switch
        FrequencyAnalysisLabel          matlab.ui.control.Label
        Switch                          matlab.ui.control.Switch
        OutlierremovalLabel             matlab.ui.control.Label
        ExportdataButton                matlab.ui.control.Button
        LoaddataButton                  matlab.ui.control.Button
        DataManagementLabel             matlab.ui.control.Label
        CenterPanel                     matlab.ui.container.Panel
        OpeninnewwindowButton           matlab.ui.control.Button
        ProcessingtracksLabel           matlab.ui.control.Label
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        ApplySavitzkyGolayCheckBox      matlab.ui.control.CheckBox
        AddtoexportCheckBox             matlab.ui.control.CheckBox
        ShowongraphCheckBox             matlab.ui.control.CheckBox
        MVCLabel                        matlab.ui.control.Label
        Slider                          matlab.ui.control.Slider
        RMSwindowsizeLabel              matlab.ui.control.Label
        DefaultButton                   matlab.ui.control.Button
        OrderEditField                  matlab.ui.control.NumericEditField
        OrderEditFieldLabel             matlab.ui.control.Label
        CutoffEditField                 matlab.ui.control.NumericEditField
        CutoffEditFieldLabel            matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        FilterDropDown                  matlab.ui.control.DropDown
        FilterDropDownLabel             matlab.ui.control.Label
        FilterparametersLabel           matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        rawdata % Description
    end
    
    methods (Access = private)
        
        function PlotRms(app, window)
           movrmsWin = dsp.MovingRMS(window);
            y1 = movrmsWin(app.rawdata);
            plot(app.UIAxes_2, y1(20:end)) 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function FilterDropDownValueChanged(app, event)
            value = app.FilterDropDown.Value;
            
        end

        % Button pushed function: LoaddataButton
        function LoaddataButtonPushed(app, event)
            [file,path] = uigetfile('*.txt');
            if isequal(file,0)
               disp('User selected Cancel');
            else
                disp(fullfile(path,file))
               [data, t, header] = FileParser(fullfile(path,file))

               disp(['User selected ', fullfile(path,file)]);
               
               selection = uiconfirm(app.UIFigure,'Please select MVC for M. Trap. Descendens (L)!','MVC M. Trap. Descendens (L)',...
                                    'Icon','info');
                % Handle response
                switch selection
                    case 'OK'
                        [file,path] = uigetfile('*.txt');
                        if isequal(file,0)
                           disp('User selected Cancel');
                        else
                            app.lampCH1.Color = [0 1 0];
                            % Plot moving RMS
                            app.rawdata = data(:,3);

                            PlotRms(app, round(app.Slider.Value));


                        end
                    case 'Cancel'
                        disp('...')
                end

            end
        end

        % Button pushed function: ExportdataButton
        function ExportdataButtonPushed(app, event)
            uigetdir(matlabroot,'Choose folder to save the analysis');
            app.Label_2.Visible = 'on';
        end

        % Callback function
        function LoadMVCButtonPushed(app, event)
            [file,path] = uigetfile('*.txt');
            if isequal(file,0)
               disp('User selected Cancel');
            else
               disp(['User selected ', fullfile(path,file)]);
               app.lampCH1.Color = [0 1 0];
            end   
        end

        % Callback function
        function CleardataButtonPushed(app, event)

            selection = uiconfirm(app.UIFigure,'Clear data?','Confirm Clearing',...
                                    'Icon','warning');
            % Handle response
            switch selection
                case 'OK'
                    disp('Clearing...')
                    app.lampCH1.Color = [1 0 0];
                    app.Lamp.Color = [1 0 0];
                case 'Cancel'
                    disp('...')
            end
        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            checkedNodes = app.Tree.CheckedNodes
           
            checkedNodes(length(checkedNodes))
            if (~isempty(checkedNodes))
                for c = 1:length(checkedNodes)
                    if (checkedNodes(c).Text == 'M. Trap. Descendens (L)')
                        disp("1 selected")
                
                    elseif (checkedNodes(c).Text == 'M. Trap. Descendens (R)')
                        disp("2 selected")

                    elseif (checkedNodes(c).Text == 'M. Deltoïdeus Anterior (L)')
                        disp("3 selected")

                    elseif (checkedNodes(c).Text == 'M. Deltoïdeus Anterior (R)')
                        disp("4 selected")

                    elseif (checkedNodes(c).Text == 'M. Ext. Carpi Radialis (dom.)')
                        disp("5 selected")
                    else
                        disp("idk")
                    end
                end
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            disp(round(value))
            PlotRms(app, round(value));
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DataManagementLabel = uilabel(app.LeftPanel);
            app.DataManagementLabel.Text = 'Data Management';
            app.LoaddataButton = uibutton(app.LeftPanel, 'push');
            app.LoaddataButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddataButtonPushed, true);
            app.LoaddataButton.Text = 'Load data';
            app.ExportdataButton = uibutton(app.LeftPanel, 'push');
            app.ExportdataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportdataButtonPushed, true);
            app.ExportdataButton.Text = 'Export data';
            app.OutlierremovalLabel = uilabel(app.LeftPanel);
            app.OutlierremovalLabel.Text = 'Outlier removal';
            app.Switch = uiswitch(app.LeftPanel, 'slider');
            app.Switch.Value = 'On';
            app.FrequencyAnalysisLabel = uilabel(app.LeftPanel);
            app.FrequencyAnalysisLabel.Text = 'Frequency Analysis';
            app.Switch_2 = uiswitch(app.LeftPanel, 'slider');
            app.Switch_2.Value = 'On';
            app.AutomaticfilterparametersLabel = uilabel(app.LeftPanel);
            app.AutomaticfilterparametersLabel.Text = {'Automatic '; 'filter parameters'};
            app.MethodDropDownLabel = uilabel(app.LeftPanel);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.LeftPanel);
            app.MethodDropDown.Items = {'STD', 'IQR', 'Z-Score', 'Percentile', 'Winsorization'};
            app.MethodDropDown.Value = 'STD';
            app.Image_2 = uiimage(app.LeftPanel);
            app.Image_2.ImageSource = 'logo.png';
            app.Tree = uitree(app.LeftPanel, 'checkbox');
            app.MTrapDescendensLNode = uitreenode(app.Tree);
            app.MTrapDescendensLNode.Text = 'M. Trap. Descendens (L)';
            app.MTrapDescendensRNode = uitreenode(app.Tree);
            app.MTrapDescendensRNode.Text = 'M. Trap. Descendens (R)';
            app.MDeltodeusAnteriorLNode = uitreenode(app.Tree);
            app.MDeltodeusAnteriorLNode.Text = 'M. Deltoïdeus Anterior (L)';
            app.MDeltodeusAnteriorRNode = uitreenode(app.Tree);
            app.MDeltodeusAnteriorRNode.Text = 'M. Deltoïdeus Anterior (R)';
            app.MExtCarpiRadialisdomNode = uitreenode(app.Tree);
            app.MExtCarpiRadialisdomNode.Text = 'M. Ext. Carpi Radialis (dom.)';
            % Assign Checked Nodes
            app.Tree.CheckedNodes = [app.MTrapDescendensLNode];
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.lampCH1 = uilamp(app.LeftPanel);
            app.lampCH2 = uilamp(app.LeftPanel);
            app.lampCH3 = uilamp(app.LeftPanel);
            app.lampCH4 = uilamp(app.LeftPanel);
            app.lampCH5 = uilamp(app.LeftPanel);
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Rectification with Band-pass')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.CenterPanel);
            title(app.UIAxes_2, 'Moving RMS window')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.ProcessingtracksLabel = uilabel(app.CenterPanel);
            app.ProcessingtracksLabel.Text = 'Processing tracks';
            app.OpeninnewwindowButton = uibutton(app.CenterPanel, 'push');
            app.OpeninnewwindowButton.Text = 'Open in new window';
            app.RightPanel = uipanel(app.GridLayout);
            app.FilterparametersLabel = uilabel(app.RightPanel);
            app.FilterparametersLabel.Text = 'Filter parameters';
            app.FilterDropDownLabel = uilabel(app.RightPanel);
            app.FilterDropDownLabel.Text = 'Filter';
            app.FilterDropDown = uidropdown(app.RightPanel);
            app.FilterDropDown.Items = {'Band-pass', 'Low-pass', 'High-pass', 'Notch', 'Savitzky-Golay'};
            app.FilterDropDown.Value = 'Band-pass';
            app.Image = uiimage(app.RightPanel);
            app.CutoffEditFieldLabel = uilabel(app.RightPanel);
            app.CutoffEditFieldLabel.Text = 'Cutoff';
            app.CutoffEditField = uieditfield(app.RightPanel, 'numeric');
            app.OrderEditFieldLabel = uilabel(app.RightPanel);
            app.OrderEditFieldLabel.Text = 'Order';
            app.OrderEditField = uieditfield(app.RightPanel, 'numeric');
            app.DefaultButton = uibutton(app.RightPanel, 'push');
            app.DefaultButton.Text = 'Default';
            app.RMSwindowsizeLabel = uilabel(app.RightPanel);
            app.RMSwindowsizeLabel.Text = 'RMS window size';
            app.Slider = uislider(app.RightPanel);
            app.Slider.Limits = [1 30];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 1;
            app.MVCLabel = uilabel(app.RightPanel);
            app.MVCLabel.Text = 'MVC';
            app.ShowongraphCheckBox = uicheckbox(app.RightPanel);
            app.ShowongraphCheckBox.Text = 'Show on graph';
            app.AddtoexportCheckBox = uicheckbox(app.RightPanel);
            app.AddtoexportCheckBox.Text = 'Add to export';
            app.ApplySavitzkyGolayCheckBox = uicheckbox(app.RightPanel);
            app.ApplySavitzkyGolayCheckBox.Text = 'Apply Savitzky-Golay';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end